classdef gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        TabGroup                   matlab.ui.container.TabGroup
        HomeTab                    matlab.ui.container.Tab
        PortDropdownDropDown       matlab.ui.control.DropDown
        PortDropdownDropDownLabel  matlab.ui.control.Label
        SamplerateandLGFFPanel     matlab.ui.container.Panel
        QueryLGFFButton            matlab.ui.control.Button
        LGFFLabel                  matlab.ui.control.Label
        RateLabel                  matlab.ui.control.Label
        QueryRateButton            matlab.ui.control.Button
        SetRateButton              matlab.ui.control.Button
        RateFieldEditField         matlab.ui.control.NumericEditField
        RateFieldEditFieldLabel    matlab.ui.control.Label
        AngleControlPanel          matlab.ui.container.Panel
        YawValueLabel              matlab.ui.control.Label
        PitchValueLabel            matlab.ui.control.Label
        QueryYawButton             matlab.ui.control.Button
        QueryPitchButton           matlab.ui.control.Button
        SetPitchButton             matlab.ui.control.Button
        SetYawButton               matlab.ui.control.Button
        PitchFieldEditField        matlab.ui.control.NumericEditField
        PitchFieldEditFieldLabel   matlab.ui.control.Label
        YawFieldEditField          matlab.ui.control.NumericEditField
        YawFieldEditFieldLabel     matlab.ui.control.Label
        AbortButton                matlab.ui.control.Button
        ResetButton                matlab.ui.control.Button
        SaveLogButton              matlab.ui.control.Button
        ClearLogButton             matlab.ui.control.Button
        StreamingStatusLabel       matlab.ui.control.Label
        ResumeButton               matlab.ui.control.Button
        PauseButton                matlab.ui.control.Button
        StopButton                 matlab.ui.control.Button
        StartButton                matlab.ui.control.Button
        StatusLabel                matlab.ui.control.Label
        DisconnectButton           matlab.ui.control.Button
        ConnectButton              matlab.ui.control.Button
        UITable                    matlab.ui.control.Table
        UIAxes                     matlab.ui.control.UIAxes
        GraphsTab                  matlab.ui.container.Tab
        UIAxes2_3                  matlab.ui.control.UIAxes
        UIAxes2_2                  matlab.ui.control.UIAxes
        UIAxes2                    matlab.ui.control.UIAxes
        HelpTab                    matlab.ui.container.Tab
        Image                      matlab.ui.control.Image
        CalibrationTab             matlab.ui.container.Tab
        CalibrationStatusLabel     matlab.ui.control.Label
        CalibrateButton            matlab.ui.control.Button
    end

    
    properties (Access = private)
        Client CommsClient_Beginner
        PlotBufferSize = 100
        PlotData = table([], [], [], [], 'VariableNames',{'t','ax','ay','az'})
        PlotLines


    end
    
    methods (Access = private)
        
        function updateStreamLabel(app,evtType)
            switch lower(evtType)
                case "streaming"
                    app.StreamingStatusLabel.Text = "Status: Streaming";
                case "paused"
                    app.StreamingStatusLabel.Text = "Status: Paused";
                case "resumed"
                    app.StreamingStatusLabel.Text = "Status: Streaming";
                case "stopped"
                    app.StreamingStatusLabel.Text = "Status: Stopped";
                case "reset"
                    app.StreamingStatusLabel.Text = "Status: Reset";
                case "fault"
                    app.StreamingStatusLabel.Text = "Status: Aborted";
                otherwise
                   
            end
            
        end
        
        function updateTelemetry(app,data)
            %Update table
            currentData = app.UITable.Data;
            newRow = {data.t, data.ax, data.ay, data.az};

            if isempty(currentData)
                newData = newRow;
            else
                newData = [currentData; newRow];
            end

            app.UITable.Data = newData;

            %Update Plots
            app.PlotData(end+1,:) = newRow;
            if height(app.UIAxes) > app.PlotBufferSize
                app.PlotData(1,:) = []; %Scroll
            end

            app.PlotLines.ax.XData = app.PlotData.t;
            app.PlotLines.ax.YData = app.PlotData.ax;

            app.PlotLines.ay.XData = app.PlotData.t;
            app.PlotLines.ay.YData = app.PlotData.ay;


            app.PlotLines.az.XData = app.PlotData.t;
            app.PlotLines.az.YData = app.PlotData.az;
            
            
        end
        
        
        function handleReply(app,r)
            switch lower(r.type)
                case "yaw"
                    app.YawValueLabel.Text = sprintf("Yaw: %.1f deg", r.value);
                case "pitch"
                    app.PitchValueLabel.Text = sprintf("Pitch: %.1f deg", r.value);
                case "rate"
                    app.RateLabel.Text = sprintf("Rate: %d Hz", r.value);
                case "lgff"
                    app.LGFFLabel.Text = sprintf("LGFF: pitch=%.1f deg, yaw=%.1f deg", r.pitch, r.yaw);

                otherwise
                    % Can handle PONG or ECHO
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Client = CommsClient_Beginner();
            app.PortDropdownDropDown.Items = serialportlist("available");
            app.StatusLabel.Text = "Status: Disconnected";
            app.DisconnectButton.Enable = "off";
            app.Client.OnEvent = @(e) updateStreamLabel(app,e.type);
            app.Client.OnTelemetry = @(data) updateTelemetry(app,data);
            app.Client.OnReply = @(r) handleReply(app,r);

            %Configure plot
            hold(app.UIAxes,"on");
            app.PlotLines.ax = plot(app.UIAxes,NaN,NaN,"r","DisplayName","ax");
            app.PlotLines.ay = plot(app.UIAxes,NaN,NaN,"g","DisplayName","ay");
            app.PlotLines.az = plot(app.UIAxes,NaN,NaN,"b","DisplayName","az");

            legend(app.UIAxes);
            grid(app.UIAxes,"on");
            title(app.UIAxes,"Live IMU Acceleration");
            xlabel(app.UIAxes, "Time (s)");
            ylabel(app.UIAxes, "Acceleration (m/s^2)"); 

        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            try
                port = app.PortDropdownDropDown.Value;
                app.Client.connect(port,115200);
                app.StatusLabel.Text = "Status: Connected";
                app.ConnectButton.Enable = "off";
                app.DisconnectButton.Enable = "on";
            catch err
                uialert(app.UIFigure,"Connection failed: " + err.message, "Error" );
                
            end
        end

        % Button pushed function: DisconnectButton
        function DisconnectButtonPushed(app, event)
            try
                app.Client.disconnect();
                app.StatusLabel.Text = "Status: Disconnected";
                app.ConnectButton.Enable = "on";
                app.DisconnectButton.Enable = "off";

            catch 
                % Silent fail for now
                
            end    
               
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            if app.Client.IsConnected
                app.Client.startStream();
                
                
            else
                uialert(app.UIFigure, "Please connect first.","Not Connected");
                
            end
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            if app.Client.IsConnected
                app.Client.stopStream();
              
                
            end
        end

        % Button pushed function: PauseButton
        function PauseButtonPushed(app, event)
            if app.Client.IsConnected
                app.Client.pauseStream();
            
                
            end
        end

        % Button pushed function: ResumeButton
        function ResumeButtonPushed(app, event)
            if app.Client.IsConnected
                app.Client.resumeStream();
                
                
            end
        end

        % Button pushed function: ClearLogButton
        function ClearLogButtonPushed(app, event)
            app.UITable.Data = {};
            app.Client.clearLog();

            app.PlotData = app.PlotData([],:); %clear buffer

            app.PlotLines.ax.XData = NaN; app.PlotLines.ax.YData = NaN;
            app.PlotLines.ay.XData = NaN; app.PlotLines.ay.YData = NaN;
            app.PlotLines.az.XData = NaN; app.PlotLines.az.YData = NaN;


        end

        % Button pushed function: SaveLogButton
        function SaveLogButtonPushed(app, event)
            [file, path] = uiputfile("*.csv","Save telemetry log");
            if isequal(file, 0)
                return;
                
            end
            app.Client.saveToCSV(fullfile(path, file));
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            if app.Client.IsConnected
                app.Client.resetSystem();
            else
                uialert(app.UIFigure, "Not connected to device.","Connection Error");
            end
        end

        % Button pushed function: AbortButton
        function AbortButtonPushed(app, event)
            if app.Client.IsConnected
                app.Client.abort();
            else
                uialert(app.UIFigure,"Not connected to device.", "Connection Error");
            end
        end

        % Button pushed function: SetYawButton
        function SetYawButtonPushed(app, event)
            if app.Client.IsConnected
                angle = app.YawFieldEditField.Value;
                app.Client.setYaw(angle);
            end
        end

        % Button pushed function: QueryYawButton
        function QueryYawButtonPushed(app, event)
            if app.Client.IsConnected
                app.Client.queryYaw();
            end
        end

        % Button pushed function: SetPitchButton
        function SetPitchButtonPushed(app, event)
            if app.Client.IsConnected
                angle = app.PitchFieldEditField.Value;
                app.Client.setPitch(angle);
            end
        end

        % Button pushed function: QueryPitchButton
        function QueryPitchButtonPushed(app, event)
            if app.Client.IsConnected
                app.Client.queryPitch();
            end
        end

        % Button pushed function: SetRateButton
        function SetRateButtonPushed(app, event)
            if app.Client.IsConnected
                hz = app.RateFieldEditField.Value;
                app.Client.setRate(hz);
            end
        end

        % Button pushed function: QueryRateButton
        function QueryRateButtonPushed(app, event)
            if app.Client.IsConnected
                app.Client.queryRate();
            end
        end

        % Button pushed function: QueryLGFFButton
        function QueryLGFFButtonPushed(app, event)
            if app.Client.IsConnected
                app.Client.queryLGFF();
            end
        end

        % Button pushed function: CalibrateButton
        function CalibrateButtonPushed(app, event)
                
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.HomeTab = uitab(app.TabGroup);
            app.HomeTab.Title = 'Home';
            app.UIAxes = uiaxes(app.HomeTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UITable = uitable(app.HomeTab);
            app.UITable.ColumnName = {'t'; 'ax'; 'ay'; 'az'};
            app.UITable.RowName = {};
            app.ConnectButton = uibutton(app.HomeTab, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'ConnectButton';
            app.DisconnectButton = uibutton(app.HomeTab, 'push');
            app.DisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectButtonPushed, true);
            app.DisconnectButton.Text = 'DisconnectButton';
            app.StatusLabel = uilabel(app.HomeTab);
            app.StatusLabel.Text = 'StatusLabel';
            app.StartButton = uibutton(app.HomeTab, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'StartButton';
            app.StopButton = uibutton(app.HomeTab, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'StopButton';
            app.PauseButton = uibutton(app.HomeTab, 'push');
            app.PauseButton.ButtonPushedFcn = createCallbackFcn(app, @PauseButtonPushed, true);
            app.PauseButton.Text = 'PauseButton';
            app.ResumeButton = uibutton(app.HomeTab, 'push');
            app.ResumeButton.ButtonPushedFcn = createCallbackFcn(app, @ResumeButtonPushed, true);
            app.ResumeButton.Text = 'ResumeButton';
            app.StreamingStatusLabel = uilabel(app.HomeTab);
            app.StreamingStatusLabel.Text = 'StreamingStatusLabel';
            app.ClearLogButton = uibutton(app.HomeTab, 'push');
            app.ClearLogButton.ButtonPushedFcn = createCallbackFcn(app, @ClearLogButtonPushed, true);
            app.ClearLogButton.Text = 'ClearLogButton';
            app.SaveLogButton = uibutton(app.HomeTab, 'push');
            app.SaveLogButton.ButtonPushedFcn = createCallbackFcn(app, @SaveLogButtonPushed, true);
            app.SaveLogButton.Text = 'SaveLogButton';
            app.ResetButton = uibutton(app.HomeTab, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'ResetButton';
            app.AbortButton = uibutton(app.HomeTab, 'push');
            app.AbortButton.ButtonPushedFcn = createCallbackFcn(app, @AbortButtonPushed, true);
            app.AbortButton.Text = 'AbortButton';
            app.AngleControlPanel = uipanel(app.HomeTab);
            app.AngleControlPanel.Title = 'Angle Control';
            app.YawFieldEditFieldLabel = uilabel(app.AngleControlPanel);
            app.YawFieldEditFieldLabel.Text = 'YawField';
            app.YawFieldEditField = uieditfield(app.AngleControlPanel, 'numeric');
            app.PitchFieldEditFieldLabel = uilabel(app.AngleControlPanel);
            app.PitchFieldEditFieldLabel.Text = 'PitchField';
            app.PitchFieldEditField = uieditfield(app.AngleControlPanel, 'numeric');
            app.SetYawButton = uibutton(app.AngleControlPanel, 'push');
            app.SetYawButton.ButtonPushedFcn = createCallbackFcn(app, @SetYawButtonPushed, true);
            app.SetYawButton.Text = 'SetYawButton';
            app.SetPitchButton = uibutton(app.AngleControlPanel, 'push');
            app.SetPitchButton.ButtonPushedFcn = createCallbackFcn(app, @SetPitchButtonPushed, true);
            app.SetPitchButton.Text = 'SetPitchButton';
            app.QueryPitchButton = uibutton(app.AngleControlPanel, 'push');
            app.QueryPitchButton.ButtonPushedFcn = createCallbackFcn(app, @QueryPitchButtonPushed, true);
            app.QueryPitchButton.Text = 'QueryPitchButton';
            app.QueryYawButton = uibutton(app.AngleControlPanel, 'push');
            app.QueryYawButton.ButtonPushedFcn = createCallbackFcn(app, @QueryYawButtonPushed, true);
            app.QueryYawButton.Text = 'QueryYawButton';
            app.PitchValueLabel = uilabel(app.AngleControlPanel);
            app.PitchValueLabel.Text = 'PitchValueLabel';
            app.YawValueLabel = uilabel(app.AngleControlPanel);
            app.YawValueLabel.Text = 'YawValueLabel';
            app.SamplerateandLGFFPanel = uipanel(app.HomeTab);
            app.SamplerateandLGFFPanel.Title = 'Sample rate and LGFF';
            app.RateFieldEditFieldLabel = uilabel(app.SamplerateandLGFFPanel);
            app.RateFieldEditFieldLabel.Text = 'RateField';
            app.RateFieldEditField = uieditfield(app.SamplerateandLGFFPanel, 'numeric');
            app.SetRateButton = uibutton(app.SamplerateandLGFFPanel, 'push');
            app.SetRateButton.ButtonPushedFcn = createCallbackFcn(app, @SetRateButtonPushed, true);
            app.SetRateButton.Text = 'SetRateButton';
            app.QueryRateButton = uibutton(app.SamplerateandLGFFPanel, 'push');
            app.QueryRateButton.ButtonPushedFcn = createCallbackFcn(app, @QueryRateButtonPushed, true);
            app.QueryRateButton.Text = 'QueryRateButton';
            app.RateLabel = uilabel(app.SamplerateandLGFFPanel);
            app.RateLabel.Text = 'RateLabel';
            app.LGFFLabel = uilabel(app.SamplerateandLGFFPanel);
            app.LGFFLabel.Text = 'LGFFLabel';
            app.QueryLGFFButton = uibutton(app.SamplerateandLGFFPanel, 'push');
            app.QueryLGFFButton.ButtonPushedFcn = createCallbackFcn(app, @QueryLGFFButtonPushed, true);
            app.QueryLGFFButton.Text = 'QueryLGFFButton';
            app.PortDropdownDropDownLabel = uilabel(app.HomeTab);
            app.PortDropdownDropDownLabel.Text = 'PortDropdown';
            app.PortDropdownDropDown = uidropdown(app.HomeTab);
            app.GraphsTab = uitab(app.TabGroup);
            app.GraphsTab.Title = 'Graphs';
            app.UIAxes2 = uiaxes(app.GraphsTab);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2_2 = uiaxes(app.GraphsTab);
            title(app.UIAxes2_2, 'Title')
            xlabel(app.UIAxes2_2, 'X')
            ylabel(app.UIAxes2_2, 'Y')
            zlabel(app.UIAxes2_2, 'Z')
            app.UIAxes2_3 = uiaxes(app.GraphsTab);
            title(app.UIAxes2_3, 'Title')
            xlabel(app.UIAxes2_3, 'X')
            ylabel(app.UIAxes2_3, 'Y')
            zlabel(app.UIAxes2_3, 'Z')
            app.HelpTab = uitab(app.TabGroup);
            app.HelpTab.Title = 'Help';
            app.Image = uiimage(app.HelpTab);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'protocol.png');
            app.CalibrationTab = uitab(app.TabGroup);
            app.CalibrationTab.Title = 'Calibration';
            app.CalibrateButton = uibutton(app.CalibrationTab, 'push');
            app.CalibrateButton.ButtonPushedFcn = createCallbackFcn(app, @CalibrateButtonPushed, true);
            app.CalibrateButton.Text = 'Calibrate';
            app.CalibrationStatusLabel = uilabel(app.CalibrationTab);
            app.CalibrationStatusLabel.Text = 'CalibrationStatusLabel';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end